classdef termo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        IRRefImageUI                   matlab.ui.control.Image
        ChoosereferenceIRimageButton   matlab.ui.control.Button
        VISRefImageUI                  matlab.ui.control.Image
        ChoosereferenceVISimageButton  matlab.ui.control.Button
        RegisterImageButton            matlab.ui.control.Button
        RegistrationResultUI           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        RegistrationTransform % Description
        VISRefImage, IRRefImage, % Description
        FixedIR
    end
    
    methods (Access = private)
        function validated = validateIRVISSelectedPoints(app)
            validated = ~isempty(app.IRRefImage) && ~isempty(app.VISRefImage);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChoosereferenceIRimageButton
        function ChoosereferenceIRimageButtonPushed(app, event)
            [file,path] = uigetfile('*.png', 'Select a Reference IR Image File');
            app.IRRefImageUI.ImageSource = fullfile(path,file);
            app.IRRefImage = imread(fullfile(path,file));
            
            app.RegisterImageButton.Enable = app.validateIRVISSelectedPoints();
        end

        % Button pushed function: ChoosereferenceVISimageButton
        function ChoosereferenceVISimageButtonPushed(app, event)
            [file,path] = uigetfile('*.jpg', 'Select a Reference VIS Image File');
            app.VISRefImageUI.ImageSource = fullfile(path,file);
            app.VISRefImage = imread(fullfile(path,file));
            
            app.RegisterImageButton.Enable = app.validateIRVISSelectedPoints();
        end

        % Button pushed function: RegisterImageButton
        function RegisterImageButtonPushed(app, event)
            
            app.FixedIR = false;
            if(app.FixedIR)
                fixed = app.IRRefImage; % fixed = root image
                moving = app.VISRefImage;
                %%%% only testing purposes start
                points = load('fixedIRPoints.mat');
                %%%% only testing purposes end        
            else 
                fixed = app.VISRefImage; % fixed = root image
                moving = app.IRRefImage;
                %%%% only testing purposes start
                points = load('fixedVISPoints.mat');
                %%%% only testing purposes end    
            end
            %%%% only testing purposes start
            movingPoints = points.fixedPoints;
            fixedPoints = points.movingPoints;
            %%%% only testing purposes end
            
%             [fixedPoints, movingPoints] = cpselect(fixed, moving,'Wait',true);

            tform = fitgeotrans(movingPoints, fixedPoints, 'projective');
            movingRegistered = imwarp(moving, tform, 'OutputView', imref2d(size(fixed)));

            imshowpair(fixed, movingRegistered, 'Parent', app.RegistrationResultUI);            
            app.RegistrationResultUI.Title.String = 'Registration result';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.IRRefImageUI = uiimage(app.GridLayout);
            app.ChoosereferenceIRimageButton = uibutton(app.GridLayout, 'push');
            app.ChoosereferenceIRimageButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosereferenceIRimageButtonPushed, true);
            app.ChoosereferenceIRimageButton.Text = 'Choose reference IR image';
            app.VISRefImageUI = uiimage(app.GridLayout);
            app.ChoosereferenceVISimageButton = uibutton(app.GridLayout, 'push');
            app.ChoosereferenceVISimageButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosereferenceVISimageButtonPushed, true);
            app.ChoosereferenceVISimageButton.Text = 'Choose reference VIS image';
            app.RegisterImageButton = uibutton(app.GridLayout, 'push');
            app.RegisterImageButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterImageButtonPushed, true);
            app.RegisterImageButton.Text = 'Register Image';
            app.RegistrationResultUI = uiaxes(app.GridLayout);
            title(app.RegistrationResultUI, 'Title')
            xlabel(app.RegistrationResultUI, '')
            ylabel(app.RegistrationResultUI, '')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = termo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end